classdef dsp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SounddatafromamicrophoneTab    matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        ParametersPanel                matlab.ui.container.Panel
        NumberofSamplesLabel           matlab.ui.control.Label
        WindowShiftEditField           matlab.ui.control.NumericEditField
        WindowShiftEditFieldLabel      matlab.ui.control.Label
        WindowTypeListBox_2            matlab.ui.control.ListBox
        WindowTypeListBox_2Label       matlab.ui.control.Label
        NumberofSamplesLabel_2         matlab.ui.control.Label
        WindowLengthEditField          matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel     matlab.ui.control.Label
        SpectogramButton               matlab.ui.control.Button
        StartPlayButton                matlab.ui.control.Button
        StopRecordButton               matlab.ui.control.Button
        StartRecordButton              matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        HzLabel                        matlab.ui.control.Label
        SamplingRateEditField          matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel     matlab.ui.control.Label
        SpectrogramPanel               matlab.ui.container.Panel
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        SounddatafromafileTab          matlab.ui.container.Tab
        SpectrogramPanel_2             matlab.ui.container.Panel
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ParametersPanel_2              matlab.ui.container.Panel
        WindowLengthEditField_2        matlab.ui.control.NumericEditField
        WindowLengthEditField_2Label   matlab.ui.control.Label
        WindowShiftEditField_2         matlab.ui.control.NumericEditField
        WindowShiftEditField_2Label    matlab.ui.control.Label
        WindowTypeListBox_3            matlab.ui.control.ListBox
        WindowTypeListBox_3Label       matlab.ui.control.Label
        NumberofSamplesLabel_4         matlab.ui.control.Label
        NumberofSamplesLabel_3         matlab.ui.control.Label
        SpectogramButton_2             matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        StatusLabel_2                  matlab.ui.control.Label
        PlayButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        DatagenerationTab              matlab.ui.container.Tab
        SpectrogramPanel_3             matlab.ui.container.Panel
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        ParametersPanel_3              matlab.ui.container.Panel
        DeltaEditField                 matlab.ui.control.EditField
        DeltaEditFieldLabel            matlab.ui.control.Label
        secLabel_3                     matlab.ui.control.Label
        NumberofSamplesLabel_6         matlab.ui.control.Label
        NumberofSamplesLabel_5         matlab.ui.control.Label
        WindowLengthEditField_3        matlab.ui.control.NumericEditField
        WindowLengthEditField_3Label   matlab.ui.control.Label
        WindowShiftEditField_3         matlab.ui.control.NumericEditField
        WindowShiftEditField_3Label    matlab.ui.control.Label
        SpectogramButton_3             matlab.ui.control.Button
        WindowTypeListBox              matlab.ui.control.ListBox
        WindowTypeListBoxLabel         matlab.ui.control.Label
        HzLabel_3                      matlab.ui.control.Label
        SamplingRateEditField_2        matlab.ui.control.NumericEditField
        SamplingRateEditField_2Label   matlab.ui.control.Label
        NumberofCompnentsSpinner       matlab.ui.control.Spinner
        NumberofCompnentsSpinnerLabel  matlab.ui.control.Label
        AmplitudesEditField            matlab.ui.control.EditField
        AmplitudesEditFieldLabel       matlab.ui.control.Label
        FrequenciesEditField           matlab.ui.control.EditField
        FrequenciesEditFieldLabel      matlab.ui.control.Label
        Sin_LengthEditField            matlab.ui.control.EditField
        Sin_LengthEditFieldLabel       matlab.ui.control.Label
        StartingTimesEditField         matlab.ui.control.EditField
        StartingTimesEditFieldLabel    matlab.ui.control.Label
        PhasesEditField                matlab.ui.control.EditField
        PhasesEditFieldLabel           matlab.ui.control.Label
        secLabel_2                     matlab.ui.control.Label
        secLabel                       matlab.ui.control.Label
        degreeLabel                    matlab.ui.control.Label
        HzLabel_2                      matlab.ui.control.Label
        VoltLabel                      matlab.ui.control.Label
        SignalTypeDropDown             matlab.ui.control.DropDown
        SignalTypeDropDownLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        Recorder; % Description
        loadedAudioFile; % Description
    end
    
    methods (Access = private)
        
        function [S, T, F] = spec(~, signal, winSize, winShift, winType, samplingRate, signalDuration)
             noverlap=winSize-winShift;
             totalSamples = floor((signalDuration) * samplingRate + 1);
            
             % Create the window based on the specified type
             if strcmp(winType, "Rectangular")
                 windowVec = transpose(rectwin(winSize));
             end
             if strcmp(winType, "Hamming")
                 windowVec = transpose(hamming(winSize));
             end
            
             numWindows = floor((length(signal) - noverlap) / winShift);
            
             freqVectorLength = ceil((winSize + 1) / 2);
            
             T = 0:0.1:(totalSamples-1)/samplingRate;
             F = ((0:freqVectorLength-1)/winSize)*samplingRate;
             stftMatrix = zeros(numWindows, freqVectorLength);
             segmentStart = 1;
             for k = 1:numWindows
                 segment = signal(segmentStart:segmentStart + winSize - 1);
                 segmentFFT = fft(segment .* windowVec);
                 stftMatrix(k, :) = segmentFFT(1:freqVectorLength);
                 segmentStart = segmentStart + winShift;
             end
            
             S = transpose(stftMatrix);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function StartRecordingButtonValueChanged(app, event)
          
        end

        % Callback function
        function RecordButtonValueChanged(app, event)
        
        end

        % Button pushed function: StartRecordButton
        function StartRecordButtonPushed(app, event)
            samplingRate = app.SamplingRateEditField.Value;
            app.Recorder = audiorecorder(8000,8,1);
            record(app.Recorder);
            app.Label.Text = 'Recording...';
        end

        % Button pushed function: StopRecordButton
        function StopRecordButtonPushed(app, event)
            stop(app.Recorder);
            app.Label.Text = 'Recording stopped.';
        end

        % Button pushed function: StartPlayButton
        function StartPlayButtonPushed(app, event)
            recordedData = getaudiodata(app.Recorder);
            if ~isempty(recordedData)
                app.Label.Text = 'Playing...';
                player = audioplayer(recordedData, app.Recorder.SampleRate);
                playblocking(player); % Play the audio 
            else
                app.Label.Text = 'No recorded data available.';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.wav';'*.mp3';}, 'Select an Audio File');
            if ischar(filename)
                % An audio file was selected
                app.loadedAudioFile = fullfile(pathname, filename);
                app.Label_3.Text = sprintf('Loaded: %s', filename);
            else
                % No file was selected or the user canceled the operation
                app.StatusLabel.Text = 'No audio file selected.';
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            [audioData, sampleRate] = audioread(app.loadedAudioFile);
            player = audioplayer(audioData, sampleRate);
            app.Label_3.Text = 'Playing...';
            playblocking(player); % Play the audio and block further actions until playback is finished
            
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            value = app.SignalTypeDropDown.Value;
            switch value
                case 'Sinusoidal signal'
                    app.NumberofCompnentsSpinner.Enable = 'off';
                    app.NumberofCompnentsSpinner.Value = 1;

                case 'Windowed sinusoidal'
                    app.NumberofCompnentsSpinner.Enable = 'off';
                    app.NumberofCompnentsSpinner.Value = 1;

                case 'Signal involving multiple components'
                    app.NumberofCompnentsSpinner.Enable = 'on';


            end
        end

        % Button pushed function: SpectogramButton
        function SpectogramButtonPushed(app, event)
            clear app.UIAxes4;
            clear app.UIAxes;
            recordedData = getaudiodata(app.Recorder);
            samplingRate = app.SamplingRateEditField.Value;
            n = length(recordedData)-1;
            max_time = n / samplingRate;
            t = 0:(1/samplingRate):max_time;
            plot(app.UIAxes,t,recordedData);
            app.Label_3.Text = 'Plotting';
            
            windowSize = app.WindowLengthEditField.Value;
            windowShift = app.WindowShiftEditField.Value;
            windowType = app.WindowTypeListBox_2.Value;
            [S,T,F]=spec(app,recordedData,windowSize,windowShift,windowType,samplingRate,max_time);
            
 
            axes(app.UIAxes4);
            imagesc(app.UIAxes4,T, F, (20*log10(abs(S))))
            axis(app.UIAxes4,'xy')
            
            xlim(app.UIAxes4,[min(T),max(T)]);
            ylim(app.UIAxes4,[min(F),max(F)]);
            colorbar(app.UIAxes4);
            
        end

        % Button pushed function: SpectogramButton_2
        function SpectogramButton_2Pushed(app, event)
            clear app.UIAxes2;
            clear app.UIAxes5;
            [recordedData, sampleRate] = audioread(app.loadedAudioFile);
            n = length(recordedData)-1;
            max_time = n / sampleRate;
            t = 0:(1/sampleRate):max_time;
            plot(app.UIAxes2,t,recordedData);
            app.Label_3.Text = 'Plotting';
            
            windowSize = app.WindowLengthEditField_2.Value;
            windowShift = app.WindowShiftEditField_2.Value;
            windowType = app.WindowTypeListBox_3.Value;
            [S,T,F]=spec(app,recordedData,windowSize,windowShift,windowType,sampleRate,max_time);
            
            axes(app.UIAxes5);
            imagesc(app.UIAxes5,T, F, 20*log10(abs(S)))
            axis(app.UIAxes5,'xy')
            xlim(app.UIAxes5,[min(T),max(T)]);
            ylim(app.UIAxes5,[min(F),max(F)]);
           
            colorbar(app.UIAxes5);
        end

        % Button pushed function: SpectogramButton_3
        function SpectogramButton_3Pushed(app, event)
            clear app.UIAxes3;
            clear app.UIAxes6;
            
            sampleRate = app.SamplingRateEditField_2.Value;
            switch app.SignalTypeDropDown.Value
                case 'Sinusoidal signal'
                    
                   amplitude = str2double(app.AmplitudesEditField.Value);
                   freq = str2double(app.FrequenciesEditField.Value);
                   phase = str2double(app.PhasesEditField.Value)/180*pi;
                   len = str2double(app.Sin_LengthEditField.Value);
                   
                   t = 0:(1/sampleRate):len;
                   signal = amplitude * cos(2*pi*freq*t+phase);
                case 'Windowed sinusoidal'
                    amplitude = str2double(app.AmplitudesEditField.Value);
                    freq = str2double(app.FrequenciesEditField.Value);
                    phase = str2double(app.PhasesEditField.Value)/180*pi;
                    len = str2double(app.Sin_LengthEditField.Value);
                    delt = str2double(app.DeltaEditField.Value);
                
                    switch app.WindowTypeListBox.Value
                        case 'Rectangular'
                            Window = rectwin(len*sampleRate+1);
                        case 'Hamming'
                            Window = hamming(len*sampleRate+1);
                    end
                    
                    t0 = str2double(app.StartingTimesEditField.Value);
                    
                    t = 0:(1/sampleRate):(len+delt); % Adjust the time vector to match the duration of the signal
                    signal = zeros(1,length(t));
                    
                    for i = 1:length(t) % Start the loop from 1
                        if t(i) >= t0 && t(i) <= t0+delt
                            windowIndex = round((t(i)-t0)*sampleRate) + 1; % Compute the index for the window
                            signal(i) = Window(windowIndex) * amplitude * cos(2 * pi * freq * (t(i)-t0) + phase);
                        end
                    end
                case 'Signal involving multiple components'
                    number_of_signals = app.NumberofCompnentsSpinner.Value;
                    amplitudes = str2double(strsplit(app.AmplitudesEditField.Value,','));
                    freqs = str2double(strsplit(app.FrequenciesEditField.Value,','));
                    phases = str2double(strsplit(app.PhasesEditField.Value,',')) /180 * pi;
                    len = str2double(app.Sin_LengthEditField.Value);
                    start_times = str2double(strsplit(app.StartingTimesEditField.Value,','));
                    deltas = str2double(strsplit(app.DeltaEditField.Value,','));
                    t = 0:(1/sampleRate):len;
                    signal = zeros(1,length(t));
                    for i = 1:number_of_signals
                        for j = 1:length(t)
                            if t(j) >= start_times(i) && t(j) <= start_times(i) + deltas(i)
                                signal(j) = signal(j) + amplitudes(i) * cos(2*pi*freqs(i) * t(j) + phases(i));
                            end
                        end
                        
                    end
            end
            
            plot(app.UIAxes3,t,signal);
            
            N = length(signal);
            signalFFT = fft(signal); 
            
            f = sampleRate*(0:(N/2))/N;
            
            magnitudeSpectrum = abs(signalFFT/N);
            magnitudeSpectrum = magnitudeSpectrum(1:N/2+1);
            
            phaseSpectrum = angle(signalFFT);
            phaseSpectrum = phaseSpectrum(1:N/2+1);
            
            plot(app.UIAxes7,f, magnitudeSpectrum) 
        
            
            plot(app.UIAxes8,f, phaseSpectrum * 180/pi) % Converting to degrees
            
            
            windowSize = app.WindowLengthEditField_3.Value;
            windowShift = app.WindowShiftEditField_3.Value;
            windowType = app.WindowTypeListBox.Value;
            
            [S,T,F]=spec(app,signal,windowSize,windowShift,windowType,sampleRate,len);
            
            axes(app.UIAxes6);
            imagesc(app.UIAxes6,T, F, 20*log10(abs(S)))
            axis(app.UIAxes6,'xy')
            colorbar(app.UIAxes6);
            xlim(app.UIAxes6,[min(T),max(T)]);
            ylim(app.UIAxes6,[min(F),max(F)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SounddatafromamicrophoneTab = uitab(app.TabGroup);
            app.SounddatafromamicrophoneTab.Title = 'Sound data from a microphone';
            app.GridLayout = uigridlayout(app.SounddatafromamicrophoneTab);
            app.SpectrogramPanel = uipanel(app.GridLayout);
            app.SpectrogramPanel.Title = 'Spectrogram';
            app.UIAxes = uiaxes(app.SpectrogramPanel);
            title(app.UIAxes, 'Time Domain Signal')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes4 = uiaxes(app.SpectrogramPanel);
            title(app.UIAxes4, 'Spectogram')
            xlabel(app.UIAxes4, 'Time (sec)')
            ylabel(app.UIAxes4, 'Frequency (Hz)')
            zlabel(app.UIAxes4, 'Z')
            app.ParametersPanel = uipanel(app.GridLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.SamplingRateEditFieldLabel = uilabel(app.ParametersPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SamplingRateEditField.Value = 8000;
            app.HzLabel = uilabel(app.ParametersPanel);
            app.HzLabel.Text = 'Hz';
            app.StatusLabel = uilabel(app.ParametersPanel);
            app.StatusLabel.Text = 'Status: ';
            app.Label = uilabel(app.ParametersPanel);
            app.Label.Text = ' ';
            app.StartRecordButton = uibutton(app.ParametersPanel, 'push');
            app.StartRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordButtonPushed, true);
            app.StartRecordButton.Text = 'Start Record';
            app.StopRecordButton = uibutton(app.ParametersPanel, 'push');
            app.StopRecordButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordButtonPushed, true);
            app.StopRecordButton.Text = 'Stop Record';
            app.StartPlayButton = uibutton(app.ParametersPanel, 'push');
            app.StartPlayButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlayButtonPushed, true);
            app.StartPlayButton.Text = 'Start Play';
            app.SpectogramButton = uibutton(app.ParametersPanel, 'push');
            app.SpectogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectogramButtonPushed, true);
            app.SpectogramButton.Text = 'Spectogram';
            app.WindowLengthEditFieldLabel = uilabel(app.ParametersPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NumberofSamplesLabel_2 = uilabel(app.ParametersPanel);
            app.NumberofSamplesLabel_2.Text = 'Number of Samples';
            app.WindowTypeListBox_2Label = uilabel(app.ParametersPanel);
            app.WindowTypeListBox_2Label.Text = 'Window Type';
            app.WindowTypeListBox_2 = uilistbox(app.ParametersPanel);
            app.WindowTypeListBox_2.Items = {'Rectangular', 'Hamming'};
            app.WindowTypeListBox_2.Value = 'Rectangular';
            app.WindowShiftEditFieldLabel = uilabel(app.ParametersPanel);
            app.WindowShiftEditFieldLabel.Text = 'Window Shift';
            app.WindowShiftEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NumberofSamplesLabel = uilabel(app.ParametersPanel);
            app.NumberofSamplesLabel.Text = 'Number of Samples';
            app.SounddatafromafileTab = uitab(app.TabGroup);
            app.SounddatafromafileTab.Title = 'Sound data from a file';
            app.ParametersPanel_2 = uipanel(app.SounddatafromafileTab);
            app.ParametersPanel_2.Title = 'Parameters';
            app.LoadButton = uibutton(app.ParametersPanel_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.ParametersPanel_2, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StatusLabel_2 = uilabel(app.ParametersPanel_2);
            app.StatusLabel_2.Text = 'Status:';
            app.Label_3 = uilabel(app.ParametersPanel_2);
            app.Label_3.Text = ' ';
            app.SpectogramButton_2 = uibutton(app.ParametersPanel_2, 'push');
            app.SpectogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpectogramButton_2Pushed, true);
            app.SpectogramButton_2.Text = 'Spectogram';
            app.NumberofSamplesLabel_3 = uilabel(app.ParametersPanel_2);
            app.NumberofSamplesLabel_3.Text = 'Number of Samples';
            app.NumberofSamplesLabel_4 = uilabel(app.ParametersPanel_2);
            app.NumberofSamplesLabel_4.Text = 'Number of Samples';
            app.WindowTypeListBox_3Label = uilabel(app.ParametersPanel_2);
            app.WindowTypeListBox_3Label.Text = 'Window Type';
            app.WindowTypeListBox_3 = uilistbox(app.ParametersPanel_2);
            app.WindowTypeListBox_3.Items = {'Rectangular', 'Hamming'};
            app.WindowTypeListBox_3.Value = 'Rectangular';
            app.WindowShiftEditField_2Label = uilabel(app.ParametersPanel_2);
            app.WindowShiftEditField_2Label.Text = 'Window Shift';
            app.WindowShiftEditField_2 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.WindowLengthEditField_2Label = uilabel(app.ParametersPanel_2);
            app.WindowLengthEditField_2Label.Text = 'Window Length';
            app.WindowLengthEditField_2 = uieditfield(app.ParametersPanel_2, 'numeric');
            app.SpectrogramPanel_2 = uipanel(app.SounddatafromafileTab);
            app.SpectrogramPanel_2.Title = 'Spectrogram';
            app.UIAxes2 = uiaxes(app.SpectrogramPanel_2);
            title(app.UIAxes2, 'Time Domain Signal')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes5 = uiaxes(app.SpectrogramPanel_2);
            title(app.UIAxes5, 'Spectogram')
            xlabel(app.UIAxes5, 'Time (s)')
            ylabel(app.UIAxes5, 'Frequency (Hz)')
            zlabel(app.UIAxes5, 'Z')
            app.DatagenerationTab = uitab(app.TabGroup);
            app.DatagenerationTab.Title = 'Data generation';
            app.ParametersPanel_3 = uipanel(app.DatagenerationTab);
            app.ParametersPanel_3.Title = 'Parameters';
            app.SignalTypeDropDownLabel = uilabel(app.ParametersPanel_3);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.ParametersPanel_3);
            app.SignalTypeDropDown.Items = {'Sinusoidal signal', 'Windowed sinusoidal', 'Signal involving multiple components'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Sinusoidal signal';
            app.VoltLabel = uilabel(app.ParametersPanel_3);
            app.VoltLabel.Text = 'Volt';
            app.HzLabel_2 = uilabel(app.ParametersPanel_3);
            app.HzLabel_2.Text = 'Hz';
            app.degreeLabel = uilabel(app.ParametersPanel_3);
            app.degreeLabel.Text = 'degree';
            app.secLabel = uilabel(app.ParametersPanel_3);
            app.secLabel.Text = 'sec';
            app.secLabel_2 = uilabel(app.ParametersPanel_3);
            app.secLabel_2.Text = 'sec';
            app.PhasesEditFieldLabel = uilabel(app.ParametersPanel_3);
            app.PhasesEditFieldLabel.Text = 'Phases';
            app.PhasesEditField = uieditfield(app.ParametersPanel_3, 'text');
            app.PhasesEditField.Value = '0';
            app.StartingTimesEditFieldLabel = uilabel(app.ParametersPanel_3);
            app.StartingTimesEditFieldLabel.Text = 'Starting Times';
            app.StartingTimesEditField = uieditfield(app.ParametersPanel_3, 'text');
            app.StartingTimesEditField.Value = '0';
            app.Sin_LengthEditFieldLabel = uilabel(app.ParametersPanel_3);
            app.Sin_LengthEditFieldLabel.Text = 'Sin_Length';
            app.Sin_LengthEditField = uieditfield(app.ParametersPanel_3, 'text');
            app.Sin_LengthEditField.Value = '3';
            app.FrequenciesEditFieldLabel = uilabel(app.ParametersPanel_3);
            app.FrequenciesEditFieldLabel.Text = 'Frequencies';
            app.FrequenciesEditField = uieditfield(app.ParametersPanel_3, 'text');
            app.FrequenciesEditField.Value = '1';
            app.AmplitudesEditFieldLabel = uilabel(app.ParametersPanel_3);
            app.AmplitudesEditFieldLabel.Text = 'Amplitudes';
            app.AmplitudesEditField = uieditfield(app.ParametersPanel_3, 'text');
            app.AmplitudesEditField.Value = '1';
            app.NumberofCompnentsSpinnerLabel = uilabel(app.ParametersPanel_3);
            app.NumberofCompnentsSpinnerLabel.Text = 'Number of Compnents';
            app.NumberofCompnentsSpinner = uispinner(app.ParametersPanel_3);
            app.NumberofCompnentsSpinner.Enable = 'off';
            app.NumberofCompnentsSpinner.Value = 1;
            app.SamplingRateEditField_2Label = uilabel(app.ParametersPanel_3);
            app.SamplingRateEditField_2Label.Text = 'Sampling Rate';
            app.SamplingRateEditField_2 = uieditfield(app.ParametersPanel_3, 'numeric');
            app.SamplingRateEditField_2.Value = 50;
            app.HzLabel_3 = uilabel(app.ParametersPanel_3);
            app.HzLabel_3.Text = 'Hz';
            app.WindowTypeListBoxLabel = uilabel(app.ParametersPanel_3);
            app.WindowTypeListBoxLabel.Text = 'Window Type';
            app.WindowTypeListBox = uilistbox(app.ParametersPanel_3);
            app.WindowTypeListBox.Items = {'Rectangular', 'Hamming'};
            app.WindowTypeListBox.Value = 'Rectangular';
            app.SpectogramButton_3 = uibutton(app.ParametersPanel_3, 'push');
            app.SpectogramButton_3.ButtonPushedFcn = createCallbackFcn(app, @SpectogramButton_3Pushed, true);
            app.SpectogramButton_3.Text = 'Spectogram';
            app.WindowShiftEditField_3Label = uilabel(app.ParametersPanel_3);
            app.WindowShiftEditField_3Label.Text = 'Window Shift';
            app.WindowShiftEditField_3 = uieditfield(app.ParametersPanel_3, 'numeric');
            app.WindowShiftEditField_3.Value = 10;
            app.WindowLengthEditField_3Label = uilabel(app.ParametersPanel_3);
            app.WindowLengthEditField_3Label.Text = 'Window Length';
            app.WindowLengthEditField_3 = uieditfield(app.ParametersPanel_3, 'numeric');
            app.WindowLengthEditField_3.Value = 10;
            app.NumberofSamplesLabel_5 = uilabel(app.ParametersPanel_3);
            app.NumberofSamplesLabel_5.Text = 'Number of Samples';
            app.NumberofSamplesLabel_6 = uilabel(app.ParametersPanel_3);
            app.NumberofSamplesLabel_6.Text = 'Number of Samples';
            app.secLabel_3 = uilabel(app.ParametersPanel_3);
            app.secLabel_3.Text = 'sec';
            app.DeltaEditFieldLabel = uilabel(app.ParametersPanel_3);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.ParametersPanel_3, 'text');
            app.DeltaEditField.Value = '3';
            app.SpectrogramPanel_3 = uipanel(app.DatagenerationTab);
            app.SpectrogramPanel_3.Title = 'Spectrogram';
            app.UIAxes3 = uiaxes(app.SpectrogramPanel_3);
            title(app.UIAxes3, 'Time Domain Signal')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes6 = uiaxes(app.SpectrogramPanel_3);
            title(app.UIAxes6, 'Spectogram')
            xlabel(app.UIAxes6, 'Time (s)')
            ylabel(app.UIAxes6, 'Frequency (Hz)')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.SpectrogramPanel_3);
            title(app.UIAxes7, 'Magnitude Response')
            xlabel(app.UIAxes7, 'Frequency (Hz)')
            ylabel(app.UIAxes7, '|X(f)|')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.SpectrogramPanel_3);
            title(app.UIAxes8, 'Phase Response')
            xlabel(app.UIAxes8, 'Frequency (Hz)')
            ylabel(app.UIAxes8, 'Phase (degrees)')
            zlabel(app.UIAxes8, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end