classdef dsp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TransmitterTab                  matlab.ui.container.Tab
        AmplitudeVEditField             matlab.ui.control.NumericEditField
        AmplitudeVEditField_3Label      matlab.ui.control.Label
        SamplingRateHzEditField         matlab.ui.control.NumericEditField
        SamplingRateHzEditField_3Label  matlab.ui.control.Label
        WindowLengthofsamplesEditField  matlab.ui.control.NumericEditField
        WindowLengthofsamplesEditField_3Label  matlab.ui.control.Label
        DuratonperkeysecEditField       matlab.ui.control.NumericEditField
        DuratonperkeysecEditField_3Label  matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        WindowTypeDropDownLabel         matlab.ui.control.Label
        WindowShiftofsamplesEditField   matlab.ui.control.NumericEditField
        WindowShiftofsamplesEditFieldLabel  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        RestingDurationsecEditField     matlab.ui.control.NumericEditField
        RestingDurationsecEditFieldLabel  matlab.ui.control.Label
        PlayButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        PressedButtonsLabel             matlab.ui.control.Label
        Button_12                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        Button_10                       matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RecieverTab                     matlab.ui.container.Tab
        LoadButton                      matlab.ui.control.Button
        WindowLengthofsamplesEditField_2  matlab.ui.control.NumericEditField
        WindowLengthofsamplesEditField_2Label  matlab.ui.control.Label
        DuratonperkeysecEditField_2     matlab.ui.control.NumericEditField
        DuratonperkeysecEditField_2Label  matlab.ui.control.Label
        DecodeButton                    matlab.ui.control.Button
        PlotButton_2                    matlab.ui.control.Button
        DecodingAlgorithmDropDown       matlab.ui.control.DropDown
        DecodingAlgorithmDropDownLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        WindowTypeDropDown_2            matlab.ui.control.DropDown
        WindowTypeDropDown_2Label       matlab.ui.control.Label
        WindowShiftofsamplesEditField_2  matlab.ui.control.NumericEditField
        WindowShiftofsamplesEditField_2Label  matlab.ui.control.Label
        SamplingRateHzEditField_2       matlab.ui.control.NumericEditField
        SamplingRateHzEditField_2Label  matlab.ui.control.Label
        AmplitudeVEditField_2           matlab.ui.control.NumericEditField
        AmplitudeVEditField_2Label      matlab.ui.control.Label
        RestingDurationsecEditField_2   matlab.ui.control.NumericEditField
        RestingDurationsecEditField_2Label  matlab.ui.control.Label
        PressedButtonsLabel_2           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lst = [];
        Recorder;
        loadedAudioFile;
    end
    
    methods (Access = private)
        
        function [S,T,F] = spec(~, signal, winSize, winShift, winType,samplingRate, signalDuration)
            noverlap=winSize-winShift;
            totalSamples = floor((signalDuration) * samplingRate + 1);
            
            % Create the window based on the specified type
            if strcmp(winType, "Rectangular")
                windowVec = transpose(rectwin(winSize));
            end
            if strcmp(winType, "Hamming")
                windowVec = transpose(hamming(winSize));
            end
            
            numWindows = floor((length(signal) - noverlap) / winShift);

             freqVectorLength = ceil((winSize + 1) / 2);
            
             T = linspace(0,(totalSamples-1)/samplingRate,numWindows);
             F = ((0:freqVectorLength-1)/winSize)*samplingRate;
             stftMatrix = zeros(numWindows, freqVectorLength);
             segmentStart = 1;
             for k = 1:numWindows
                 segment = signal(segmentStart:segmentStart + winSize - 1);
                 segmentFFT = fft(segment .* windowVec);
                 stftMatrix(k, :) = segmentFFT(1:freqVectorLength);
                 segmentStart = segmentStart + winShift;
             end
            
             S = transpose(stftMatrix);
        end
        
        function [t,signal] = generate_signal(app)
            Td = app.DuratonperkeysecEditField.Value;
            Tr = app.RestingDurationsecEditField.Value;
            A = app.AmplitudeVEditField.Value;
            n = length(app.lst);
            samplingRate = app.SamplingRateHzEditField.Value;
            length_of_signal = floor((Td + Tr) * n * samplingRate); 
            signal = zeros(1,length_of_signal);
            t = linspace(0,(Td + Tr) * n , length_of_signal);
            for i = 1:n
                row = floor((app.lst(i)-1) / 3) ;
                column = mod(app.lst(i),3);
                switch row
                    case 0
                        fl = 697;
                    case 1
                        fl = 770;
                    case 2
                        fl = 852;
                    case 3
                        fl = 941;
                end
       
                switch column
                    case 1
                        fh = 1209;
                    case 2
                        fh = 1336;
                    case 0
                        fh = 1447;
                end
                
                for j = (Td + Tr) * (i-1) * samplingRate + 1 : ((Td + Tr) * (i-1) + Td) * samplingRate
                    signal(int32(j)) = A * (sin(2 * pi * fl* (t(int32(j)) - (i-1) * (Td + Tr))) + sin(2 * pi * fh* (t(int32(j)) - (i-1) * (Td + Tr))));
                end
                
            end
            
        end
        
        function decoded_digits = decode_dtmf_goertzel(app,signal, fs, durationPerKey, restingDuration, amplitude, windowShift, windowLength, windowType)
            % Define DTMF frequency bins
            lowFreqBins = [697, 770, 852, 941];
            highFreqBins = [1209, 1336, 1477];
            keys = ['1', '2', '3'; '4', '5', '6'; '7', '8', '9'; '*', '0', '#'];
            
            % Calculate the number of samples per key and resting duration
            samplesPerKey = round(durationPerKey * fs);
            samplesResting = round(restingDuration * fs);
            
            % Initialize the output variable
            decoded_digits = '';
            
            % Initialize variables for Goertzel algorithm
            goertzelBins = [lowFreqBins, highFreqBins];
            numBins = length(goertzelBins);
            binIndices = round(goertzelBins / fs * windowLength);
            
            % Window function choice
            switch windowType
                case 'Rectangular'
                    windowFunc = rectwin(windowLength);
                % Add other cases for different window types
                    case 'Hamming'
                    windowFunc = hamming(windowLength);
                % case 'Hanning'
                %     windowFunc = hann(windowLength);
                % ...
                otherwise
                    windowFunc = rectwin(windowLength); % Default case
            end
            
            % Step through the signal in increments of windowShift
            startIndex = 1;
            while startIndex <= length(signal) - windowLength
                % Apply the window to the segment
                segment = signal(startIndex:startIndex + windowLength - 1) .* windowFunc;
                
                % Run Goertzel algorithm on the bins of interest
                magnitudes = zeros(1, numBins);
                for binIndex = 1:numBins
                    magnitudes(binIndex) = goertzel(app,segment, binIndices(binIndex));
                end
                
                % Find the two strongest frequencies in the low and high bins
                [~, lowBin] = max(magnitudes(1:length(lowFreqBins)));
                [~, highBin] = max(magnitudes(length(lowFreqBins)+1:end));
                
                % Decode the digit
                if magnitudes(lowBin) > amplitude && magnitudes(highBin+length(lowFreqBins)) > amplitude
                    row = find(lowFreqBins == goertzelBins(lowBin));
                    col = find(highFreqBins == goertzelBins(highBin+length(lowFreqBins)));
                    decoded_digit = keys(row, col);
                    decoded_digits = [decoded_digits, decoded_digit];
                   
                    % Skip over the resting period to the next key
                    startIndex = startIndex + samplesPerKey + samplesResting - windowShift;
                else
                    startIndex = startIndex + windowShift;
                end
            end
        end
        
        function magnitude = goertzel(app,segment, binIndex)
            % Initialize Goertzel variables
            prev1 = 0;
            prev2 = 0;
            for i = 1:length(segment)
                curr = segment(i) + 2 * cos(2 * pi * binIndex / length(segment)) * prev1 - prev2;
                prev2 = prev1;
                prev1 = curr;
            end
            magnitude = prev1^2 + prev2^2 - prev1 * prev2 * 2 * cos(2 * pi * binIndex / length(segment));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.lst(end+1) = 1;
            app.Label.Text = app.Label.Text + "1 ";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.lst(end+1) = 2;
            app.Label.Text = app.Label.Text + "2 ";
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.lst(end+1) = 3;
            app.Label.Text = app.Label.Text + "3 ";
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.lst(end+1) = 4;
            app.Label.Text = app.Label.Text + "4 ";
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.lst(end+1) = 5;
            app.Label.Text = app.Label.Text + "5 ";
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.lst(end+1) = 6;
            app.Label.Text = app.Label.Text + "6 ";
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.lst(end+1) = 7;
            app.Label.Text = app.Label.Text + "7 ";
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.lst(end+1) = 8;
            app.Label.Text = app.Label.Text + "8 ";
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.lst(end+1) = 9;
            app.Label.Text = app.Label.Text + "9 ";
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.lst(end+1) = 10;
            app.Label.Text = app.Label.Text + "* ";
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.lst(end+1) = 11;
            app.Label.Text = app.Label.Text + "0 ";
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.lst(end+1) = 12;
            app.Label.Text = app.Label.Text + "# ";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.lst = [];
            app.Label.Text = "";
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            clear app.UIAxes;
            clear app.UIaxes2;
            if isempty(app.lst) == false
                [t,signal] = generate_signal(app);
                plot(app.UIAxes,t,signal);
                xlim(app.UIAxes,[min(t),max(t)]);
                windowSize = app.WindowLengthofsamplesEditField.Value;
                windowShift = app.WindowShiftofsamplesEditField.Value;
                windowType = app.WindowTypeDropDown.Value;
                samplingRate = app.SamplingRateHzEditField.Value;
                [S,T,F]=spec(app,signal,windowSize,windowShift,windowType,samplingRate,max(t));
                axes(app.UIAxes2);
                imagesc(app.UIAxes2,T, F, (20*log10(abs(S))))
                axis(app.UIAxes2,'xy')
                xlim(app.UIAxes2,[min(T),max(T)]);
                ylim(app.UIAxes2,[min(F),max(F)]);
                colorbar(app.UIAxes2);
            end

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.lst) == false 
                samplingRate = app.SamplingRateHzEditField.Value;
                [t,signal] = generate_signal(app);
                sound(signal,samplingRate);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.lst) == false 
                samplingRate = app.SamplingRateHzEditField.Value;
                [t,signal] = generate_signal(app);
                signal = signal / max(abs(signal));
                audiowrite('dialing.wav', signal, samplingRate);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            samplingRate = app.SamplingRateHzEditField_2.Value;
            app.Recorder = audiorecorder(samplingRate,8,1);
            record(app.Recorder);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.Recorder);
            app.loadedAudioFile = [];
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            clear app.UIAxes2_2;
            clear app.UIAxes_2;
            
            
            if(isempty(app.loadedAudioFile))
                recordedData = getaudiodata(app.Recorder);
            else
                [recordedData, ~] = audioread(app.loadedAudioFile);
                recordedData = app.AmplitudeVEditField_2.Value * 2 * recordedData;
            end
            samplingRate = app.SamplingRateHzEditField_2.Value;
            n = length(recordedData)-1;
            max_time = n / samplingRate;
            t = 0:(1/samplingRate):max_time;
            plot(app.UIAxes_2,t,recordedData);
            
            windowSize = app.WindowLengthofsamplesEditField_2.Value;
            windowShift = app.WindowShiftofsamplesEditField_2.Value;
            windowType = app.WindowTypeDropDown_2.Value;
            
            [S,T,F]=spec(app,recordedData,windowSize,windowShift,windowType,samplingRate,max_time);
            
            axes(app.UIAxes2_2);
            imagesc(app.UIAxes2_2,T, F, (20*log10(abs(S))))
            axis(app.UIAxes2_2,'xy')
            
            xlim(app.UIAxes2_2,[min(T),max(T)]);
            ylim(app.UIAxes2_2,[min(F),max(F)]);
            colorbar(app.UIAxes2_2);

        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            fl_lst = [697 770 852 941];
            fh_lst = [1209 1336 1477];
            app.Label_2.Text = " ";
            switch app.DecodingAlgorithmDropDown.Value
                case "Spectogram Based"
                    if(isempty(app.loadedAudioFile))
                        recordedData = getaudiodata(app.Recorder);
                    else
                        [recordedData, ~] = audioread(app.loadedAudioFile);
                        recordedData = app.AmplitudeVEditField_2.Value * 2 * recordedData;
                    end
                    samplingRate = app.SamplingRateHzEditField_2.Value;
                    n = length(recordedData)-1;
                    max_time = n / samplingRate;
                    windowSize = app.WindowLengthofsamplesEditField_2.Value;
                    windowShift = app.WindowShiftofsamplesEditField_2.Value;
                    windowType = app.WindowTypeDropDown_2.Value;
                    [S,~,F]=spec(app,recordedData,windowSize,windowShift,windowType,samplingRate,max_time);
                    
                    S = abs(S);
                    sum_ = sum(S,1);
                    [~,col] = find(sum_< 10);
                    col = unique(col);
                    if col(1) ~= 1
                        zerostartlst = [col(1)-1];
                        zeroendlst = [1];
                    else 
                        zerostartlst = [];
                        zeroendlst = [];
                    end
                    for i = 2:length(col)
                        if col(i) - col(i-1) > 1
                            zerostartlst(end+1) = col(i) - 1;
                            zeroendlst(end+1) = col(i-1) + 1;
                        end
                    end
                    n = length(zeroendlst);
                    
                    F_lower_1000 = F(F<1000);
                    F_higher_1000 = F(F>1000);
                    for k = 1:n
                        S_ = S(:, zeroendlst(k):zerostartlst(k));
                        S_with_F_lower_1000 = S_(F<1000,:);
                        S_with_F_higher_1000 = S_(F>1000,:);
                        
                        [~,i] = max(S_with_F_lower_1000,[],1);
                        fl = mode(F_lower_1000(i));
                        
                        [~,i] = max(S_with_F_higher_1000,[],1);
                        fh = mode(F_higher_1000(i));
                        
                        [~,i] = min(abs(fl_lst - fl),[],2);
                        
                        [~,j] = min(abs(fh_lst - fh),[],2);
                        
                        number = (i-1)*3 + j;
                        if number < 10 
                            app.Label_2.Text = strcat(app.Label_2.Text, num2str(number));
                        elseif number == 10
                            app.Label_2.Text = app.Label_2.Text + "*";
                        elseif number == 11
                            app.Label_2.Text = app.Label_2.Text + "0";
                        elseif number == 12
                            app.Label_2.Text = app.Label_2.Text + "#";
                        end
                    end
                case "Goertzel Algorithm"
                    if(isempty(app.loadedAudioFile))
                        recordedData = getaudiodata(app.Recorder);
                    else
                        [recordedData, ~] = audioread(app.loadedAudioFile);
                        recordedData = app.AmplitudeVEditField_2.Value * 2 * recordedData;
                    end
                    samplingRate = app.SamplingRateHzEditField_2.Value;
                    windowSize = app.WindowLengthofsamplesEditField_2.Value;
                    windowShift = app.WindowShiftofsamplesEditField_2.Value;
                    windowType = app.WindowTypeDropDown_2.Value;
                    durationPerKey = app.DuratonperkeysecEditField_2.Value;
                    restingDuration = app.RestingDurationsecEditField_2.Value;
                    amplitude = app.AmplitudeVEditField_2.Value;
                    decoded_digits = decode_dtmf_goertzel(app,recordedData, samplingRate, durationPerKey, restingDuration, amplitude, windowShift, windowSize, windowType);
                    
                    app.Label_2.Text = decoded_digits;
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.wav';'*.mp3';}, 'Select an Audio File'); 
            if ischar(filename) % An audio file was selected 
                app.loadedAudioFile = fullfile(pathname, filename);
                assignin("base","signal",app.loadedAudioFile);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TransmitterTab = uitab(app.TabGroup);
            app.TransmitterTab.Title = 'Transmitter';
            app.UIAxes = uiaxes(app.TransmitterTab);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.TransmitterTab);
            title(app.UIAxes2, 'Spectogram')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'Frequency (Hz)')
            zlabel(app.UIAxes2, 'Z')
            app.Button = uibutton(app.TransmitterTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.TransmitterTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.TransmitterTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.TransmitterTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.TransmitterTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.TransmitterTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.TransmitterTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.TransmitterTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.TransmitterTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.TransmitterTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '*';
            app.Button_11 = uibutton(app.TransmitterTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.TransmitterTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.PressedButtonsLabel = uilabel(app.TransmitterTab);
            app.PressedButtonsLabel.Text = 'Pressed Buttons: ';
            app.Label = uilabel(app.TransmitterTab);
            app.Label.Text = '';
            app.ResetButton = uibutton(app.TransmitterTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.TransmitterTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlayButton = uibutton(app.TransmitterTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.RestingDurationsecEditFieldLabel = uilabel(app.TransmitterTab);
            app.RestingDurationsecEditFieldLabel.Text = 'Resting Duration (sec)';
            app.RestingDurationsecEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.RestingDurationsecEditField.Value = 0.1;
            app.PlotButton = uibutton(app.TransmitterTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.WindowShiftofsamplesEditFieldLabel = uilabel(app.TransmitterTab);
            app.WindowShiftofsamplesEditFieldLabel.Text = 'Window Shift  (# of samples)';
            app.WindowShiftofsamplesEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.WindowShiftofsamplesEditField.Value = 128;
            app.WindowTypeDropDownLabel = uilabel(app.TransmitterTab);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.TransmitterTab);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Hamming'};
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.DuratonperkeysecEditField_3Label = uilabel(app.TransmitterTab);
            app.DuratonperkeysecEditField_3Label.Text = 'Duraton-per-key (sec)';
            app.DuratonperkeysecEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.DuratonperkeysecEditField.Value = 0.1;
            app.WindowLengthofsamplesEditField_3Label = uilabel(app.TransmitterTab);
            app.WindowLengthofsamplesEditField_3Label.Text = 'Window Length (# of samples)';
            app.WindowLengthofsamplesEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.WindowLengthofsamplesEditField.Value = 256;
            app.SamplingRateHzEditField_3Label = uilabel(app.TransmitterTab);
            app.SamplingRateHzEditField_3Label.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.SamplingRateHzEditField.Value = 8000;
            app.AmplitudeVEditField_3Label = uilabel(app.TransmitterTab);
            app.AmplitudeVEditField_3Label.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.TransmitterTab, 'numeric');
            app.AmplitudeVEditField.Value = 1;
            app.RecieverTab = uitab(app.TabGroup);
            app.RecieverTab.Title = 'Reciever';
            app.UIAxes_2 = uiaxes(app.RecieverTab);
            title(app.UIAxes_2, 'Time Domain')
            xlabel(app.UIAxes_2, 'Time (sec)')
            ylabel(app.UIAxes_2, 'Amplitude (V)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RecieverTab);
            title(app.UIAxes2_2, 'Spectogram')
            xlabel(app.UIAxes2_2, 'Time (sec)')
            ylabel(app.UIAxes2_2, 'Frequency (Hz)')
            zlabel(app.UIAxes2_2, 'Z')
            app.StartButton = uibutton(app.RecieverTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecieverTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PressedButtonsLabel_2 = uilabel(app.RecieverTab);
            app.PressedButtonsLabel_2.Text = 'Pressed Buttons: ';
            app.RestingDurationsecEditField_2Label = uilabel(app.RecieverTab);
            app.RestingDurationsecEditField_2Label.Text = 'Resting Duration (sec)';
            app.RestingDurationsecEditField_2 = uieditfield(app.RecieverTab, 'numeric');
            app.RestingDurationsecEditField_2.Value = 0.1;
            app.AmplitudeVEditField_2Label = uilabel(app.RecieverTab);
            app.AmplitudeVEditField_2Label.Text = 'Amplitude (V)';
            app.AmplitudeVEditField_2 = uieditfield(app.RecieverTab, 'numeric');
            app.AmplitudeVEditField_2.Value = 1;
            app.SamplingRateHzEditField_2Label = uilabel(app.RecieverTab);
            app.SamplingRateHzEditField_2Label.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField_2 = uieditfield(app.RecieverTab, 'numeric');
            app.SamplingRateHzEditField_2.Value = 8000;
            app.WindowShiftofsamplesEditField_2Label = uilabel(app.RecieverTab);
            app.WindowShiftofsamplesEditField_2Label.Text = 'Window Shift  (# of samples)';
            app.WindowShiftofsamplesEditField_2 = uieditfield(app.RecieverTab, 'numeric');
            app.WindowShiftofsamplesEditField_2.Value = 128;
            app.WindowTypeDropDown_2Label = uilabel(app.RecieverTab);
            app.WindowTypeDropDown_2Label.Text = 'Window Type';
            app.WindowTypeDropDown_2 = uidropdown(app.RecieverTab);
            app.WindowTypeDropDown_2.Items = {'Rectangular', 'Hamming'};
            app.WindowTypeDropDown_2.Value = 'Rectangular';
            app.Label_2 = uilabel(app.RecieverTab);
            app.Label_2.Text = '';
            app.DecodingAlgorithmDropDownLabel = uilabel(app.RecieverTab);
            app.DecodingAlgorithmDropDownLabel.Text = 'Decoding Algorithm';
            app.DecodingAlgorithmDropDown = uidropdown(app.RecieverTab);
            app.DecodingAlgorithmDropDown.Items = {'Spectogram Based', 'Goertzel Algorithm'};
            app.DecodingAlgorithmDropDown.Value = 'Spectogram Based';
            app.PlotButton_2 = uibutton(app.RecieverTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.DecodeButton = uibutton(app.RecieverTab, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            app.DuratonperkeysecEditField_2Label = uilabel(app.RecieverTab);
            app.DuratonperkeysecEditField_2Label.Text = 'Duraton-per-key (sec)';
            app.DuratonperkeysecEditField_2 = uieditfield(app.RecieverTab, 'numeric');
            app.DuratonperkeysecEditField_2.Value = 0.1;
            app.WindowLengthofsamplesEditField_2Label = uilabel(app.RecieverTab);
            app.WindowLengthofsamplesEditField_2Label.Text = 'Window Length (# of samples)';
            app.WindowLengthofsamplesEditField_2 = uieditfield(app.RecieverTab, 'numeric');
            app.WindowLengthofsamplesEditField_2.Value = 256;
            app.LoadButton = uibutton(app.RecieverTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end